classdef EE415ProjectGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ProjectButton             matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes3                   matlab.ui.control.UIAxes
        BeamNumberEditFieldLabel  matlab.ui.control.Label
        BeamNumberEditField       matlab.ui.control.NumericEditField
        StepNumberEditFieldLabel  matlab.ui.control.Label
        StepNumberEditField       matlab.ui.control.NumericEditField
        ImageButtonGroup          matlab.ui.container.ButtonGroup
        SquareButton              matlab.ui.control.RadioButton
        SheppLoganButton          matlab.ui.control.RadioButton
        LenaButton                matlab.ui.control.RadioButton
        FilterTypeButtonGroup     matlab.ui.container.ButtonGroup
        TriangularButton          matlab.ui.control.RadioButton
        GaussianButton            matlab.ui.control.RadioButton
        NoFilterButton            matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BeamNumberEditField
        function BeamNumberEditFieldValueChanged(app, event)
            value = app.BeamNumberEditField.Value;
        end

        % Value changed function: StepNumberEditField
        function StepNumberEditFieldValueChanged(app, event)
            value = app.StepNumberEditField.Value;
        end

        % Selection changed function: ImageButtonGroup
        function ImageButtonGroupSelectionChanged(app, event)
            selectedButton = app.ImageButtonGroup.SelectedObject;
        end

        % Selection changed function: FilterTypeButtonGroup
        function FilterTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.FilterTypeButtonGroup.SelectedObject;
        end

        % Button pushed function: ProjectButton
        function ProjectButtonPushed(app, event)
            numberofbeams=app.BeamNumberEditField.Value;
            stepsize=app.StepNumberEditField.Value;
            switch app.ImageButtonGroup.SelectedObject.Text
                case 'Square'
                    myimage=load("square.mat");
                case 'SheppLogan'
                    myimage=load("SheppLogan.mat");
                case 'Lena'
                    myimage=load("lena.mat");
            end
            C=struct2cell(myimage);%Cell
            A=cell2mat(C);%Matrix
            %Getting Size
            sizeofmatrix=size(A);
            sizeofimage=sizeofmatrix(1,1);%Assuming image is square
            %Getting beam number and step size
            %Defining t, teta, x, and y arrays according to beam number and step size
            t=linspace(-sizeofimage*sqrt(2)/2,sizeofimage*sqrt(2)/2,numberofbeams);
            teta=linspace(0,180-(180/stepsize),stepsize);
            x=linspace(-sizeofimage/2,sizeofimage/2,sizeofimage+1);
            y=linspace(-sizeofimage/2,sizeofimage/2,sizeofimage+1);
            %Taking projections
             for i=1:(length(teta))
                 for j=1:(length(t))
            %Clearing dynamic memory
            relevantmatrix=[];
            rowdata=[]; columndata=[]; midpointx=[];
            midpointy=[]; distance=[];
            %Finding intersection points
                yfromx=(t(j)/sind(teta(i)))-(x.*cotd(teta(i)));
                xfromy=(t(j)/cosd(teta(i)))-(x.*tand(teta(i)));
            %Detecting relevant x and y coordinates
            m=1;
            for g=1:(length(x))
                if (((-sizeofimage/2)<=yfromx(g))&&(yfromx(g)<=sizeofimage/2))
                    relevantmatrix(m,1)=x(1,g);
                    relevantmatrix(m,2)=yfromx(1,g);
                    m=m+1;
                else
                end
            end
            for k=1:(length(y))
                if (((-sizeofimage/2)<=xfromy(k))&&(xfromy(k)<=sizeofimage/2))
                    relevantmatrix(m,1)=xfromy(1,k);
                    relevantmatrix(m,2)=y(1,k);
                    m=m+1;
                else
                end
            end
            uniquerelevantmatrix=unique(relevantmatrix,'rows');%Deleting same x and y points
            sortedrelevantmatrix=sortrows(uniquerelevantmatrix);%Sorting relevant x and y points
            sizerelevant=size(uniquerelevantmatrix,1);%Detecting if there are relevant points
            if sizerelevant==0 %If no relevant points exist return total attenuation
                totalattenuation=0;
                projectionmatrix(j,i)=totalattenuation;
            else
            [rowsizeofURM,columnsizeofURM]=size(uniquerelevantmatrix);
            if (rowsizeofURM>1)%If there are more than one relevant point then calculate attenuation
            %Calculating distance and midpoints to use them for detecting row and
            %column data
            for r=1:(rowsizeofURM-1)
                distance(r)=sqrt(((uniquerelevantmatrix(r,1)-uniquerelevantmatrix(r+1,1))^2)+((uniquerelevantmatrix(r,2)-uniquerelevantmatrix(r+1,2))^2));
                midpointx(r)=(uniquerelevantmatrix(r,1)+uniquerelevantmatrix(r+1,1))/2;
                midpointy(r)=(uniquerelevantmatrix(r,2)+uniquerelevantmatrix(r+1,2))/2;
            end
            %Finding corresponding column and row data
            rowdata=(sizeofimage/2)-floor(midpointy);
            columndata=(sizeofimage/2)+ceil(midpointx);
            %Summing all attenuation
            sizeofdistancematrix=size(distance);
            sizeofdistancearray=sizeofdistancematrix(1,2);
            totalattenuation=0;%Clearing dynamic data from previous calculation
            for h=1:sizeofdistancearray
                totalattenuation=totalattenuation+(A(rowdata(1,h),columndata(1,h))*distance(1,h));
            end
            projectionmatrix(j,i)=totalattenuation;%Assigning attenuation to matrix
            else%If there is only one relevant point beam is tangential, then attenuation is zero
                totalattenuation=0;
                projectionmatrix(j,i)=totalattenuation;
            end
            end
                end
             end
            colormap(app.UIAxes,'gray');
            imagesc(app.UIAxes,A);
            
            %Backprojection
            [numberofbeams stepsize]=size(projectionmatrix);
            imagematrix=zeros(sizeofimage);
            
            switch app.FilterTypeButtonGroup.SelectedObject.Text
                case 'Triangular'
                    triangfilter=triang(numberofbeams);%Designing filter in Fourier Domain
                    for o=1:stepsize
                    filteredprojectionmatrix(:,o)=ifft2((triangfilter).*(fft2(projectionmatrix(:,o))));
                    end
                    projectionmatrix=filteredprojectionmatrix;
                case 'Gaussian'
                    inpargu=1:1:numberofbeams;
                    gausfilter=normpdf(inpargu,numberofbeams/2,20);
                    gausfilter=gausfilter';
                    for o=1:stepsize
                    filteredprojectionmatrix(:,o)=ifft2((gausfilter).*(fft2(projectionmatrix(:,o))));
                    end
                    projectionmatrix=filteredprojectionmatrix;
                case 'No Filter'
                    
            end
            %Defining t, teta, x, and y arrays according to beam number and step size
            t=linspace(-sizeofimage*sqrt(2)/2,sizeofimage*sqrt(2)/2,numberofbeams);
            teta=linspace(0,180-(180/stepsize),stepsize);
            x=linspace(-sizeofimage/2,sizeofimage/2,sizeofimage+1);
            y=linspace(-sizeofimage/2,sizeofimage/2,sizeofimage+1);
             for i=1:(length(teta))
                 for j=1:(length(t))
            %Clearing dynamic memory
            relevantmatrix=[];
            rowdata=[]; columndata=[]; midpointx=[];
            midpointy=[]; distance=[];
            %Finding intersection points
                yfromx=(t(j)/sind(teta(i)))-(x.*cotd(teta(i)));
                xfromy=(t(j)/cosd(teta(i)))-(x.*tand(teta(i)));
            %Detecting relevant x and y coordinates
            m=1;
            for g=1:(length(x))
                if (((-sizeofimage/2)<=yfromx(g))&&(yfromx(g)<=sizeofimage/2))
                    relevantmatrix(m,1)=x(1,g);
                    relevantmatrix(m,2)=yfromx(1,g);
                    m=m+1;
                else
                end
            end
            for k=1:(length(y))
                if (((-sizeofimage/2)<=xfromy(k))&&(xfromy(k)<=sizeofimage/2))
                    relevantmatrix(m,1)=xfromy(1,k);
                    relevantmatrix(m,2)=y(1,k);
                    m=m+1;
                else
                end
            end
            uniquerelevantmatrix=unique(relevantmatrix,'rows');%Deleting same x and y points
            sortedrelevantmatrix=sortrows(uniquerelevantmatrix);%Sorting relevant x and y points
            sizerelevant=size(uniquerelevantmatrix,1);%Detecting if there are relevant points
            if sizerelevant==0 %If no relevant points do nothing
            else
            [rowsizeofURM,columnsizeofURM]=size(uniquerelevantmatrix);
            if (rowsizeofURM>1)%If there are more than one relevant point then calculate attenuation
            %Calculating distance and midpoints to use them for detecting row and
            %column data
            for r=1:(rowsizeofURM-1)
                distance(r)=sqrt(((uniquerelevantmatrix(r,1)-uniquerelevantmatrix(r+1,1))^2)+((uniquerelevantmatrix(r,2)-uniquerelevantmatrix(r+1,2))^2));
                midpointx(r)=(uniquerelevantmatrix(r,1)+uniquerelevantmatrix(r+1,1))/2;
                midpointy(r)=(uniquerelevantmatrix(r,2)+uniquerelevantmatrix(r+1,2))/2;
            end
            %Finding corresponding column and row data
            rowdata=(sizeofimage/2)-floor(midpointy);
            columndata=(sizeofimage/2)+ceil(midpointx);
            %Backprojecting one ray beam
            sizeofdistancematrix=size(distance);
            sizeofdistancearray=sizeofdistancematrix(1,2);
            for h=1:sizeofdistancearray
                imagematrix(rowdata(1,h),columndata(1,h))=imagematrix(rowdata(1,h),columndata(1,h))+(projectionmatrix(j,i)*distance(1,h));
            end
            else%If there is only one relevant point beam is tangential, then do nothing
            end
            end
                end
             end
            %Normalizing image matrix
            imagematrix=real(imagematrix);
            
            normimagematrix=(imagematrix-min(min(imagematrix)))/(max(max(imagematrix)));
            colormap(app.UIAxes3,'gray');
            imagesc(app.UIAxes3,normimagematrix);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ProjectButton = uibutton(app.UIFigure, 'push');
            app.ProjectButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectButtonPushed, true);
            app.ProjectButton.Text = 'Project';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Filtered Back Projected Image')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.BeamNumberEditFieldLabel = uilabel(app.UIFigure);
            app.BeamNumberEditFieldLabel.Text = 'Beam Number';
            app.BeamNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.BeamNumberEditField.ValueChangedFcn = createCallbackFcn(app, @BeamNumberEditFieldValueChanged, true);
            app.StepNumberEditFieldLabel = uilabel(app.UIFigure);
            app.StepNumberEditFieldLabel.Text = 'Step Number';
            app.StepNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepNumberEditField.ValueChangedFcn = createCallbackFcn(app, @StepNumberEditFieldValueChanged, true);
            app.ImageButtonGroup = uibuttongroup(app.UIFigure);
            app.ImageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImageButtonGroupSelectionChanged, true);
            app.ImageButtonGroup.Title = 'Image';
            app.SquareButton = uiradiobutton(app.ImageButtonGroup);
            app.SquareButton.Text = 'Square';
            app.SquareButton.Value = true;
            app.SheppLoganButton = uiradiobutton(app.ImageButtonGroup);
            app.SheppLoganButton.Text = 'SheppLogan';
            app.LenaButton = uiradiobutton(app.ImageButtonGroup);
            app.LenaButton.Text = 'Lena';
            app.FilterTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.FilterTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterTypeButtonGroupSelectionChanged, true);
            app.FilterTypeButtonGroup.Title = 'Filter Type';
            app.TriangularButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.TriangularButton.Text = 'Triangular';
            app.TriangularButton.Value = true;
            app.GaussianButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.NoFilterButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.NoFilterButton.Text = 'No Filter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EE415ProjectGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end